classdef MonitorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HRLabel                matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        BPMLabel               matlab.ui.control.Label
        HR_value_label         matlab.ui.control.Label
        SpO2Label              matlab.ui.control.Label
        PercentageLabel        matlab.ui.control.Label
        SpO2_value_label       matlab.ui.control.Label
        TempLabel              matlab.ui.control.Label
        CLabel                 matlab.ui.control.Label
        Temp_value_label       matlab.ui.control.Label
        StartStopPanel         matlab.ui.container.Panel
        StartStopButton        matlab.ui.control.Button
        GemButton              matlab.ui.control.Button
        AlarmgrnsevrdierPanel  matlab.ui.container.Panel
        SpO2Panel              matlab.ui.container.Panel
        MinLabel_2             matlab.ui.control.Label
        MaksLabel_2            matlab.ui.control.Label
        MinEditLimitSpO2       matlab.ui.control.Label
        MaxEditLimitSpO2       matlab.ui.control.Label
        HRPanel                matlab.ui.container.Panel
        MinLabel               matlab.ui.control.Label
        MaksLabel              matlab.ui.control.Label
        MinEditLimitHR         matlab.ui.control.Label
        MaxEditLimitHR         matlab.ui.control.Label
        TempPanel              matlab.ui.container.Panel
        MinLabel_3             matlab.ui.control.Label
        MaksLabel_3            matlab.ui.control.Label
        MinEditLimitTemp       matlab.ui.control.Label
        MaxEditLimitTemp       matlab.ui.control.Label
        JusterButton           matlab.ui.control.Button
        MUTEALARMButton        matlab.ui.control.Button
        STOPButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        SaveDialog                   % Dialog box app
        DataTable                    % table for data
        CurrentHR
        CurrentSpO2
        CurrentTemp
        AlarmState
        IsStarted = false
        HR = zeros(1,10);
        SpO2 = zeros(1,10);
    end


    methods (Access = public) % public funktion som kan kaldes fra andre Apps
        
        function Test_update(app, x)
            app.MinEditLimitHR.Text = x;
        end
    end
    
    methods (Access = private)
        
        function CheckAlarmValues(app)
            
            if app.CurrentHR > app.MaxEditLimitHR || app.CurrentHR < app.MinEditLimitHR
                
                %kode for at kalde Alarm
                app.AlarmState = true;
            elseif app.CurrentSpO2 > app.MaxEditLimitSpO2 || app.CurrentSpO2 < app.MinEditLimitSpO2
                
                %kode for at kalde alarm
                app.AlarmState = true;
            elseif app.CurrentTemp > app.MaxEditLimitTemp || app.CurrentTemp < app.MaxEditLimitTemp
                
                %Kode for at kalde alarm
                app.AlarmState = true;
            else
                app.AlarmState = false;
            end
            
        end
        
        function getPulseAndSpO2(app)
            % Get pulse and oxygen saturation
            
            %while (app.IsStarted == true)
            %[app.HR, app.SpO2] = processRawData();
            HR_handle = app.HR_value_label;
            SpO2_handle = app.SpO2_value_label;
            addlistener(app.HR,'processRawData',@(src,event)updateGUI(app));
            
            %end
        end
        
        function updateGUI(app)
            for ii = 1:length(app.HR)
                app.HR_value_label.Text = num2str(app.HR(ii));
                app.SpO2_value_label.Text = num2str(app.SpO2(ii));
                pause(0.2)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GemButton
        function GemButtonPushed(app, event)
            % Her skal stå kode for når der trykkes på gem knappen
            
            %Test for at Gemme noget random kode ned i tabel
            % app.DataTable = "d";
            
            % Åbner SaveDialogApp
            app.SaveDialog = SaveDialogApp(app, app.DataTable);
            
            
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            % Her skal stå kode for når der trykkes på Start/stop knappen
            
%             app.IsStarted = true;
%             app.getPulseAndSpO2();
%             HR_handle = app.HR_value_label;
%             SpO2_handle = app.SpO2_value_label;
%             
%             processRawData(app,event,HR_handle,SpO2_handle)
%             drawnow
            
        end

        % Button pushed function: MUTEALARMButton
        function MUTEALARMButtonPushed(app, event)
            % Her skal stå kode for når der trykkes på Mute alarm knappen
            app.MUTEALARMButton.Visible = false; % knappen bliver igen usynlig
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete all apps
            delete(app.SaveDialog)
            delete(app)
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.IsStarted = false;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HRLabel = uilabel(app.UIFigure);
            app.HRLabel.Text = {'HR'; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'image_processing20200511-27178-yzw8o8.png';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.HR_value_label = uilabel(app.UIFigure);
            app.HR_value_label.Text = '0';
            app.SpO2Label = uilabel(app.UIFigure);
            app.SpO2Label.Text = 'SpO2';
            app.PercentageLabel = uilabel(app.UIFigure);
            app.PercentageLabel.Text = '%';
            app.SpO2_value_label = uilabel(app.UIFigure);
            app.SpO2_value_label.Text = '0';
            app.TempLabel = uilabel(app.UIFigure);
            app.TempLabel.Text = 'Temp';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = '°C';
            app.Temp_value_label = uilabel(app.UIFigure);
            app.Temp_value_label.Text = '0';
            app.StartStopPanel = uipanel(app.UIFigure);
            app.StartStopPanel.Title = 'Start/Stop';
            app.StartStopButton = uibutton(app.StartStopPanel, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.GemButton = uibutton(app.StartStopPanel, 'push');
            app.GemButton.ButtonPushedFcn = createCallbackFcn(app, @GemButtonPushed, true);
            app.GemButton.Icon = 'save-file.png';
            app.GemButton.Text = 'Gem';
            app.AlarmgrnsevrdierPanel = uipanel(app.UIFigure);
            app.AlarmgrnsevrdierPanel.Title = 'Alarm grænseværdier';
            app.SpO2Panel = uipanel(app.AlarmgrnsevrdierPanel);
            app.SpO2Panel.Title = 'SpO2';
            app.MinLabel_2 = uilabel(app.SpO2Panel);
            app.MinLabel_2.Text = 'Min:';
            app.MaksLabel_2 = uilabel(app.SpO2Panel);
            app.MaksLabel_2.Text = 'Maks:';
            app.MinEditLimitSpO2 = uilabel(app.SpO2Panel);
            app.MinEditLimitSpO2.Text = '89';
            app.MaxEditLimitSpO2 = uilabel(app.SpO2Panel);
            app.MaxEditLimitSpO2.Text = '96';
            app.HRPanel = uipanel(app.AlarmgrnsevrdierPanel);
            app.HRPanel.Title = 'HR';
            app.MinLabel = uilabel(app.HRPanel);
            app.MinLabel.Text = 'Min:';
            app.MaksLabel = uilabel(app.HRPanel);
            app.MaksLabel.Text = 'Maks:';
            app.MinEditLimitHR = uilabel(app.HRPanel);
            app.MinEditLimitHR.Text = '100';
            app.MaxEditLimitHR = uilabel(app.HRPanel);
            app.MaxEditLimitHR.Text = '180';
            app.TempPanel = uipanel(app.AlarmgrnsevrdierPanel);
            app.TempPanel.Title = 'Temp';
            app.MinLabel_3 = uilabel(app.TempPanel);
            app.MinLabel_3.Text = 'Min:';
            app.MaksLabel_3 = uilabel(app.TempPanel);
            app.MaksLabel_3.Text = 'Maks:';
            app.MinEditLimitTemp = uilabel(app.TempPanel);
            app.MinEditLimitTemp.Text = '36,5';
            app.MaxEditLimitTemp = uilabel(app.TempPanel);
            app.MaxEditLimitTemp.Text = '37,5';
            app.JusterButton = uibutton(app.AlarmgrnsevrdierPanel, 'push');
            app.JusterButton.Text = 'Juster ';
            app.MUTEALARMButton = uibutton(app.UIFigure, 'push');
            app.MUTEALARMButton.ButtonPushedFcn = createCallbackFcn(app, @MUTEALARMButtonPushed, true);
            app.MUTEALARMButton.Visible = 'off';
            app.MUTEALARMButton.Text = 'MUTE ALARM';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonitorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end